classdef app_control_encoder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DC_Motor_Speed_Control          matlab.ui.Figure
        SpeedrpmGaugeLabel              matlab.ui.control.Label
        SpeedrpmGauge                   matlab.ui.control.Gauge
        Lamp                            matlab.ui.control.Lamp
        Switch                          matlab.ui.control.Switch
        draw                            matlab.ui.control.UIAxes
        SetSpeedEditFieldLabel          matlab.ui.control.Label
        SetSpeedEditField               matlab.ui.control.NumericEditField
        KiEditFieldLabel                matlab.ui.control.Label
        KiEditField                     matlab.ui.control.NumericEditField
        KdEditFieldLabel                matlab.ui.control.Label
        KdEditField                     matlab.ui.control.NumericEditField
        CurrentSpeedEditFieldLabel      matlab.ui.control.Label
        CurrentSpeedEditField           matlab.ui.control.NumericEditField
        KpEditFieldLabel                matlab.ui.control.Label
        KpEditField                     matlab.ui.control.NumericEditField
        COMEditFieldLabel               matlab.ui.control.Label
        COMEditField                    matlab.ui.control.NumericEditField
        CONTROLDCMOTORSPEEDUSEPIDLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            clc
            COM = strcat('COM',num2str(app.COMEditField.Value))
              delete(instrfind('Port',COM));
              s=serial(COM,'BaudRate',9600);
             fopen(s);
             v=[];
             set = [];
            
             
            while 1
                readData=fscanf(s);
                
             speed = app.SetSpeedEditField.Value;
             kp = app.KpEditField.Value;
             ki = app.KiEditField.Value;
             kd = app.KdEditField.Value;
                       
            
             fprintf(s,strcat('vs_set_speed',num2str(speed)))
             fprintf(s,strcat('vs_kp',num2str(kp)))
             fprintf(s,strcat('vs_ki',num2str(ki)))
             fprintf(s,strcat('vs_kd',num2str(kd)))
             if string(app.Switch.Value) == 'On'
                 fprintf(s,'vs_start')
                 app.Lamp.Enable = 'On';
                 disp('On')
             end  
             if string(app.Switch.Value) == 'Off'
                 fprintf(s,'vs_stop')
                 app.Lamp.Enable = 'Off';
                 disp('Off')   
                 
             end
              readData=fscanf(s);

             rpm = str2num(readData);  
             
           app.SpeedrpmGauge.Value = rpm;
           app.CurrentSpeedEditField.Value = rpm;
           v=[v rpm];
           set = [set speed];
           plot(app.draw,set,'r')
           hold(app.draw)
           plot(app.draw,v)
           ylim(app.draw, [0,100])
           xlim(app.draw, [0, inf])
             drawnow;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DC_Motor_Speed_Control = uifigure('Visible', 'off');
            app.DC_Motor_Speed_Control.Name = 'UI Figure';
            app.SpeedrpmGaugeLabel = uilabel(app.DC_Motor_Speed_Control);
            app.SpeedrpmGaugeLabel.Text = 'Speed (rpm)';
            app.SpeedrpmGauge = uigauge(app.DC_Motor_Speed_Control, 'circular');
            app.Lamp = uilamp(app.DC_Motor_Speed_Control);
            app.Switch = uiswitch(app.DC_Motor_Speed_Control, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.draw = uiaxes(app.DC_Motor_Speed_Control);
            title(app.draw, 'Draw')
            xlabel(app.draw, 'Time (s)')
            ylabel(app.draw, 'Speed (rpm)')
            app.SetSpeedEditFieldLabel = uilabel(app.DC_Motor_Speed_Control);
            app.SetSpeedEditFieldLabel.Text = 'Set Speed';
            app.SetSpeedEditField = uieditfield(app.DC_Motor_Speed_Control, 'numeric');
            app.KiEditFieldLabel = uilabel(app.DC_Motor_Speed_Control);
            app.KiEditFieldLabel.Text = 'Ki';
            app.KiEditField = uieditfield(app.DC_Motor_Speed_Control, 'numeric');
            app.KdEditFieldLabel = uilabel(app.DC_Motor_Speed_Control);
            app.KdEditFieldLabel.Text = 'Kd';
            app.KdEditField = uieditfield(app.DC_Motor_Speed_Control, 'numeric');
            app.CurrentSpeedEditFieldLabel = uilabel(app.DC_Motor_Speed_Control);
            app.CurrentSpeedEditFieldLabel.Text = 'Current Speed';
            app.CurrentSpeedEditField = uieditfield(app.DC_Motor_Speed_Control, 'numeric');
            app.CurrentSpeedEditField.Limits = [0 100];
            app.KpEditFieldLabel = uilabel(app.DC_Motor_Speed_Control);
            app.KpEditFieldLabel.Text = 'Kp';
            app.KpEditField = uieditfield(app.DC_Motor_Speed_Control, 'numeric');
            app.COMEditFieldLabel = uilabel(app.DC_Motor_Speed_Control);
            app.COMEditFieldLabel.Text = 'COM';
            app.COMEditField = uieditfield(app.DC_Motor_Speed_Control, 'numeric');
            app.CONTROLDCMOTORSPEEDUSEPIDLabel = uilabel(app.DC_Motor_Speed_Control);
            app.CONTROLDCMOTORSPEEDUSEPIDLabel.Text = 'CONTROL DC MOTOR SPEED USE PID';
            % Show the figure after all components are created
            app.DC_Motor_Speed_Control.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_control_encoder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DC_Motor_Speed_Control)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DC_Motor_Speed_Control)
        end
    end
end